classdef GetTokenApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        SaveTokenButton           matlab.ui.control.Button
        OpenTokenGeneratorButton  matlab.ui.control.Button
        EntertokenTextArea        matlab.ui.control.TextArea
        EntertokenLabel           matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: OpenTokenGeneratorButton
        function OpenTokenGeneratorButtonPushed(app, event)
            web('https://lab.ebrains.eu/hub/oauth_login?next=https://lab.ebrains.eu/user-redirect/lab/tree/shared/Data%20Curation/EBRAINS-token.ipynb')
        end

        % Button pushed function: SaveTokenButton
        function SaveTokenButtonPushed(app, event)
            if isempty(app.EntertokenTextArea.Value); return; end
            setenv('EBRAINS_ACCESS_TOKEN', app.EntertokenTextArea.Value)
            uiresume(app.UIFigure)
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.EntertokenLabel = uilabel(app.UIFigure);
            app.EntertokenLabel.Text = 'Enter token:';
            app.EntertokenTextArea = uitextarea(app.UIFigure);
            app.OpenTokenGeneratorButton = uibutton(app.UIFigure, 'push');
            app.OpenTokenGeneratorButton.ButtonPushedFcn = createCallbackFcn(app, @OpenTokenGeneratorButtonPushed, true);
            app.OpenTokenGeneratorButton.Text = 'Open Token Generator';
            app.SaveTokenButton = uibutton(app.UIFigure, 'push');
            app.SaveTokenButton.ButtonPushedFcn = createCallbackFcn(app, @SaveTokenButtonPushed, true);
            app.SaveTokenButton.Text = 'Save Token';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GetTokenApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end